classdef GameOfLife < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AddspontaneousbirthsSlider  matlab.ui.control.Slider
        AddspontaneousbirthsSliderLabel  matlab.ui.control.Label
        ClearboardButton            matlab.ui.control.Button
        RandomfillButton            matlab.ui.control.Button
        InitializeButton            matlab.ui.control.Button
        NextCycleButton             matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        StartButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        game % game of life object
    end
    
    methods (Access = private)
        
        % resize board to image format and print it
        function printBoard(app)
            image = repmat(imresize(app.game.board, app.Image.Position(3:4), 'nearest'), 1, 1, 3);
            app.Image.ImageSource = image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if (app.StartButton.Text == "Start")
                app.StartButton.Text = "Stop";
            else
                app.StartButton.Text = "Start";
            end
            while app.StartButton.Text == "Stop"
                app.game.nextCycle();
                app.printBoard();
                pause(0.05);
            end
        end

        % Button pushed function: NextCycleButton
        function NextCycleButtonPushed(app, event)
            app.game.nextCycle();
            app.printBoard()
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.game = Life(50, 50);
            app.printBoard();
            app.StartButton.Enable = true;
            app.RandomfillButton.Enable = true;
            app.ClearboardButton.Enable = true;
            app.NextCycleButton.Enable = true;
            app.AddspontaneousbirthsSlider.Enable = true;
            app.AddspontaneousbirthsSliderLabel.Enable = true;
            app.Image.Visible = true;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            pixelCoord = app.UIFigure.CurrentPoint - app.Image.Position(1:2);
            pixelCoord(2) = app.Image.Position(4) - pixelCoord(2);
            pixelCoord = ceil(pixelCoord ./ app.Image.Position(3:4) .* app.game.size);
            app.game.board(pixelCoord(2), pixelCoord(1)) = ~app.game.board(pixelCoord(2), pixelCoord(1));
            app.printBoard();
        end

        % Button pushed function: RandomfillButton
        function RandomfillButtonPushed(app, event)
            app.game.fillRand();
            app.printBoard();
        end

        % Button pushed function: ClearboardButton
        function ClearboardButtonPushed(app, event)
            app.game.board = app.game.board .* 0;
            app.printBoard();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.StartButton.Text = "Start";
            delete(app);
        end

        % Value changing function: AddspontaneousbirthsSlider
        function AddspontaneousbirthsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.game.birthFromVoid = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Visible = 'off';
            app.NextCycleButton = uibutton(app.UIFigure, 'push');
            app.NextCycleButton.ButtonPushedFcn = createCallbackFcn(app, @NextCycleButtonPushed, true);
            app.NextCycleButton.Enable = 'off';
            app.NextCycleButton.Text = 'Next Cycle';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.RandomfillButton = uibutton(app.UIFigure, 'push');
            app.RandomfillButton.ButtonPushedFcn = createCallbackFcn(app, @RandomfillButtonPushed, true);
            app.RandomfillButton.Enable = 'off';
            app.RandomfillButton.Text = 'Random fill';
            app.ClearboardButton = uibutton(app.UIFigure, 'push');
            app.ClearboardButton.ButtonPushedFcn = createCallbackFcn(app, @ClearboardButtonPushed, true);
            app.ClearboardButton.Enable = 'off';
            app.ClearboardButton.Text = 'Clear board';
            app.AddspontaneousbirthsSliderLabel = uilabel(app.UIFigure);
            app.AddspontaneousbirthsSliderLabel.Enable = 'off';
            app.AddspontaneousbirthsSliderLabel.Text = 'Add spontaneous births';
            app.AddspontaneousbirthsSlider = uislider(app.UIFigure);
            app.AddspontaneousbirthsSlider.ValueChangingFcn = createCallbackFcn(app, @AddspontaneousbirthsSliderValueChanging, true);
            app.AddspontaneousbirthsSlider.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GameOfLife

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end